classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LayersLabel                matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        TabGroup                   matlab.ui.container.TabGroup
        SegmentCellsTab            matlab.ui.container.Tab
        ChannelEditFieldLabel      matlab.ui.control.Label
        ChannelEditField           matlab.ui.control.NumericEditField
        ClosefilterButton          matlab.ui.control.StateButton
        FilterOutliersButton       matlab.ui.control.StateButton
        FillholesButton            matlab.ui.control.StateButton
        ThresholdingButtonGroup    matlab.ui.container.ButtonGroup
        SimpleThresh_value         matlab.ui.control.NumericEditField
        Percentile_value           matlab.ui.control.NumericEditField
        LocalPercentile_value      matlab.ui.control.NumericEditField
        SimpleButton               matlab.ui.control.Button
        PercentileButton           matlab.ui.control.Button
        LocalButton                matlab.ui.control.Button
        AbsMaxVol                  matlab.ui.control.NumericEditField
        VolumesEditFieldLabel      matlab.ui.control.Label
        AbsMinVol                  matlab.ui.control.NumericEditField
        background_value           matlab.ui.control.NumericEditField
        TileSizeEditFieldLabel     matlab.ui.control.Label
        TileSizeEditField          matlab.ui.control.NumericEditField
        ClipLimitEditFieldLabel    matlab.ui.control.Label
        ClipLimitEditField         matlab.ui.control.NumericEditField
        AlphaEditFieldLabel        matlab.ui.control.Label
        AlphaEditField             matlab.ui.control.NumericEditField
        BetaEditFieldLabel         matlab.ui.control.Label
        BetaEditField              matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel      matlab.ui.control.Label
        EpsilonEditField           matlab.ui.control.NumericEditField
        SubBackChkbox              matlab.ui.control.CheckBox
        CLAHEChkbox                matlab.ui.control.CheckBox
        CheckBox                   matlab.ui.control.CheckBox
        DeltaEditFieldLabel        matlab.ui.control.Label
        DeltaEditField             matlab.ui.control.NumericEditField
        SubbackgroundButton        matlab.ui.control.Button
        claheButton                matlab.ui.control.Button
        gradientimageButton        matlab.ui.control.Button
        GammaEditFieldLabel        matlab.ui.control.Label
        GammaEditField             matlab.ui.control.NumericEditField
        MedianFilterPanel          matlab.ui.container.Panel
        xyEditFieldLabel           matlab.ui.control.Label
        xyEditField                matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        zEditField                 matlab.ui.control.NumericEditField
        SigmaGradientPanel         matlab.ui.container.Panel
        xyEditField_2Label         matlab.ui.control.Label
        xyEditField_2              matlab.ui.control.NumericEditField
        zEditField_2Label          matlab.ui.control.Label
        zEditField_2               matlab.ui.control.NumericEditField
        imclose_radius_value       matlab.ui.control.NumericEditField
        MeanfilterButton           matlab.ui.control.Button
        SensitivityEditFieldLabel  matlab.ui.control.Label
        SensitivityEditField       matlab.ui.control.NumericEditField
        RESETButton                matlab.ui.control.Button
        outlier_threshold_value    matlab.ui.control.NumericEditField
        WatershedButton            matlab.ui.control.StateButton
        hmindepthEditFieldLabel    matlab.ui.control.Label
        hmindepthEditField         matlab.ui.control.NumericEditField
        MergerButton               matlab.ui.control.StateButton
        mergedistEditFieldLabel    matlab.ui.control.Label
        mergedistEditField         matlab.ui.control.NumericEditField
        xyEditField_3Label         matlab.ui.control.Label
        mean_filter_xy_value       matlab.ui.control.NumericEditField
        zLabel                     matlab.ui.control.Label
        mean_filter_z_value        matlab.ui.control.NumericEditField
        SegmentSpotsTab            matlab.ui.container.Tab
        TrackCellsTab              matlab.ui.container.Tab
    end

    
    properties (Access = public )
        reader
        image_bits
        channel
        Image
        Filtered % filtered image
        BW% current binarized image
        stats
        curr_z
        curr_t
        obj % track_analyzer object
        %update_img=@update_img
        %update_stk=@update_stack
        toolboxApp
        view_layer
        img_dim
    end
    
    methods (Access = public)
        
        function update_image(app)
            
            % Create histograms based on number of color channels
            switch length(size(app.Image))
                case 1
                    % Display the grayscale image
                    imagesc(app.UIAxes,app.Image);
                    
                case 3
                    %Estimate range. 
                    img_range = double([min(app.(app.view_layer)(:)),max(app.(app.view_layer)(:))]);
                    % Display the truecolor image
                    imshow(app.(app.view_layer)(:,:,app.curr_z),img_range,'Parent',app.UIAxes)
            end
            
            figure(10)
            imshow3D(app.(app.view_layer));
        end
        
        function update_stack(app)
            app.Image = get_stack(app.reader,app.curr_t,app.channel);
            update_image(app)
            %p=1
        end
       
        function return_str = channel_str(app)
        
            return_str = ['channel_',pad(num2str(app.channel),2,'left','0')];
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj)

            % Configure image axes
            %app.ImageAxes.Visible = 'off';
            %app.ImageAxes.Colormap = gray(256);
            %axis(app.ImageAxes, 'image');
            drawnow
            % Update the image and histograms
            img_file_name = obj.exp_info.img_file;
            
            % initialize time and channels. 
            app.curr_t=1;
            app.channel=1;
            app.curr_z=1;
            app.obj=obj;
            % Add image reader to app
            app.reader = bfGetReader(img_file_name);
            app.image_bits=app.reader.getBitsPerPixel;   
            % Update components
            app.ZSlider.Limits=[1,obj.exp_info.z_planes];
            frames = length(obj.exp_info.time_series);
            if frames == 1
                app.TimeSlider.Limits=[1,2];
            else
                app.TimeSlider.Limits=[1,length(obj.exp_info.time_series)];
            end
            app.view_layer='Image';
            %Initialize image
            update_stack(app);
            app.img_dim = length(size(app.Image));

            % Call diaglog app
            %app.toolboxApp=tool_box(app);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            
            verticalScrollCount = event.VerticalScrollCount;
            app.curr_z = app.curr_z - verticalScrollCount;
            if (app.curr_z < 1)
                app.curr_z = 1;
            elseif (app.curr_z > app.obj.exp_info.z_planes)
                app.curr_z = app.obj.exp_info.z_planes;
            end
            
            %Update image
            app.UIAxes.Children.CData=app.(app.view_layer)(:,:,app.curr_z);
            
            %drawnow
            
            %Update slider. 
            %app.ZSlider.Value=app.curr_z;
            
        end

        % Callback function
        function t_change(app, event)
            app.curr_t = app.TimeSlider.Value;
            update_stack(app)
        end

        % Value changed function: DropDown
        function layer_change(app, event)
            value = app.DropDown.Value;
            
            % Figure out which layer to display. 
            switch value
                
                case 'Image'
                    
                    app.view_layer='Image';
                    
                case 'Filtered'
                    % Check if filtered exists yet. 
                    if isempty(app.Filtered)
                        app.view_layer='Image';
                    else
                        app.view_layer='Filtered';
                    end
                    
                case 'BW'      
                    % Check if BW exists yet. 
                    if isempty(app.BW)
                        app.view_layer='Image';
                    else
                        app.view_layer='BW';
                    end              
            end
            % Set current layer. 
            app.update_image();
            
        end

        % Button pushed function: RESETButton
        function reset_button(app, event)
            app.seg_steps = app.initialize_seg_steps;
            
            app.CallingApp.update_stack();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.LayersLabel = uilabel(app.UIFigure);
            app.LayersLabel.Text = 'Layers';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Image', 'Filtered', 'BW'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @layer_change, true);
            app.DropDown.Value = 'Image';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SegmentCellsTab = uitab(app.TabGroup);
            app.SegmentCellsTab.Title = 'Segment Cells';
            app.ChannelEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.ChannelEditField.Limits = [1 10];
            app.ChannelEditField.RoundFractionalValues = 'on';
            app.ChannelEditField.Value = 1;
            app.ClosefilterButton = uibutton(app.SegmentCellsTab, 'state');
            app.ClosefilterButton.Text = 'Close filter';
            app.FilterOutliersButton = uibutton(app.SegmentCellsTab, 'state');
            app.FilterOutliersButton.Text = 'Filter Outliers';
            app.FillholesButton = uibutton(app.SegmentCellsTab, 'state');
            app.FillholesButton.Text = 'Fill holes';
            app.ThresholdingButtonGroup = uibuttongroup(app.SegmentCellsTab);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.SimpleThresh_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.Percentile_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.LocalPercentile_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.SimpleButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.SimpleButton.Text = 'Simple';
            app.PercentileButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.PercentileButton.Text = 'Percentile';
            app.LocalButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.LocalButton.Text = 'Local';
            app.AbsMaxVol = uieditfield(app.SegmentCellsTab, 'numeric');
            app.VolumesEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.VolumesEditFieldLabel.Text = 'Volumes';
            app.AbsMinVol = uieditfield(app.SegmentCellsTab, 'numeric');
            app.background_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.TileSizeEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.TileSizeEditFieldLabel.Text = 'Tile Size';
            app.TileSizeEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.ClipLimitEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.ClipLimitEditFieldLabel.Text = 'Clip Limit';
            app.ClipLimitEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.AlphaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.EpsilonEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SubBackChkbox = uicheckbox(app.SegmentCellsTab);
            app.SubBackChkbox.Text = {''; ''};
            app.CLAHEChkbox = uicheckbox(app.SegmentCellsTab);
            app.CLAHEChkbox.Text = {''; ''};
            app.CheckBox = uicheckbox(app.SegmentCellsTab);
            app.CheckBox.Text = {''; ''};
            app.DeltaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SubbackgroundButton = uibutton(app.SegmentCellsTab, 'push');
            app.SubbackgroundButton.Text = 'Sub background';
            app.claheButton = uibutton(app.SegmentCellsTab, 'push');
            app.claheButton.Text = 'clahe';
            app.gradientimageButton = uibutton(app.SegmentCellsTab, 'push');
            app.gradientimageButton.Text = 'gradient image';
            app.GammaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MedianFilterPanel = uipanel(app.SegmentCellsTab);
            app.MedianFilterPanel.Title = 'Median Filter';
            app.xyEditFieldLabel = uilabel(app.MedianFilterPanel);
            app.xyEditFieldLabel.Text = 'xy';
            app.xyEditField = uieditfield(app.MedianFilterPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.MedianFilterPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.MedianFilterPanel, 'numeric');
            app.SigmaGradientPanel = uipanel(app.SegmentCellsTab);
            app.SigmaGradientPanel.Title = 'Sigma Gradient';
            app.xyEditField_2Label = uilabel(app.SigmaGradientPanel);
            app.xyEditField_2Label.Text = 'xy';
            app.xyEditField_2 = uieditfield(app.SigmaGradientPanel, 'numeric');
            app.zEditField_2Label = uilabel(app.SigmaGradientPanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.SigmaGradientPanel, 'numeric');
            app.imclose_radius_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MeanfilterButton = uibutton(app.SegmentCellsTab, 'push');
            app.MeanfilterButton.Text = 'Mean filter';
            app.SensitivityEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.RESETButton = uibutton(app.SegmentCellsTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @reset_button, true);
            app.RESETButton.Text = 'RESET';
            app.outlier_threshold_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.WatershedButton = uibutton(app.SegmentCellsTab, 'state');
            app.WatershedButton.Text = 'Watershed';
            app.hmindepthEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.hmindepthEditFieldLabel.Text = 'hmin depth';
            app.hmindepthEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MergerButton = uibutton(app.SegmentCellsTab, 'state');
            app.MergerButton.Text = 'Merger';
            app.mergedistEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.mergedistEditFieldLabel.Text = 'merge dist';
            app.mergedistEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.xyEditField_3Label = uilabel(app.SegmentCellsTab);
            app.xyEditField_3Label.Text = 'xy';
            app.mean_filter_xy_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.zLabel = uilabel(app.SegmentCellsTab);
            app.zLabel.Text = 'z';
            app.mean_filter_z_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SegmentSpotsTab = uitab(app.TabGroup);
            app.SegmentSpotsTab.Title = 'Segment Spots';
            app.TrackCellsTab = uitab(app.TabGroup);
            app.TrackCellsTab.Title = 'Track Cells';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end