classdef main3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        SegmentCellsTab            matlab.ui.container.Tab
        ThresholdingButtonGroup    matlab.ui.container.ButtonGroup
        SimpleThresh_value         matlab.ui.control.NumericEditField
        Percentile_value           matlab.ui.control.NumericEditField
        LocalPercentile_value      matlab.ui.control.NumericEditField
        SimpleButton               matlab.ui.control.Button
        PercentileButton           matlab.ui.control.Button
        LocalButton                matlab.ui.control.Button
        AbsMaxVol                  matlab.ui.control.NumericEditField
        VolumesEditFieldLabel      matlab.ui.control.Label
        AbsMinVol                  matlab.ui.control.NumericEditField
        background_value           matlab.ui.control.NumericEditField
        TileSizeEditFieldLabel     matlab.ui.control.Label
        TileSizeEditField          matlab.ui.control.NumericEditField
        ClipLimitEditFieldLabel    matlab.ui.control.Label
        ClipLimitEditField         matlab.ui.control.NumericEditField
        AlphaEditFieldLabel        matlab.ui.control.Label
        AlphaEditField             matlab.ui.control.NumericEditField
        BetaEditFieldLabel         matlab.ui.control.Label
        BetaEditField              matlab.ui.control.NumericEditField
        EpsilonEditFieldLabel      matlab.ui.control.Label
        EpsilonEditField           matlab.ui.control.NumericEditField
        SubBackChkbox              matlab.ui.control.CheckBox
        CLAHEChkbox                matlab.ui.control.CheckBox
        CheckBox                   matlab.ui.control.CheckBox
        DeltaEditFieldLabel        matlab.ui.control.Label
        DeltaEditField             matlab.ui.control.NumericEditField
        SubbackgroundButton        matlab.ui.control.Button
        claheButton                matlab.ui.control.Button
        gradientimageButton        matlab.ui.control.Button
        GammaEditFieldLabel        matlab.ui.control.Label
        GammaEditField             matlab.ui.control.NumericEditField
        MedianFilterPanel          matlab.ui.container.Panel
        xyEditFieldLabel           matlab.ui.control.Label
        xyEditField                matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        zEditField                 matlab.ui.control.NumericEditField
        SigmaGradientPanel         matlab.ui.container.Panel
        xyEditField_2Label         matlab.ui.control.Label
        xyEditField_2              matlab.ui.control.NumericEditField
        zEditField_2Label          matlab.ui.control.Label
        zEditField_2               matlab.ui.control.NumericEditField
        imclose_radius_value       matlab.ui.control.NumericEditField
        MeanfilterButton           matlab.ui.control.Button
        SensitivityEditFieldLabel  matlab.ui.control.Label
        SensitivityEditField       matlab.ui.control.NumericEditField
        outlier_threshold_value    matlab.ui.control.NumericEditField
        hmindepthEditFieldLabel    matlab.ui.control.Label
        hmindepthEditField         matlab.ui.control.NumericEditField
        MergerButton               matlab.ui.control.StateButton
        mergedistEditFieldLabel    matlab.ui.control.Label
        mergedistEditField         matlab.ui.control.NumericEditField
        xyEditField_3Label         matlab.ui.control.Label
        mean_filter_xy_value       matlab.ui.control.NumericEditField
        zLabel                     matlab.ui.control.Label
        mean_filter_z_value        matlab.ui.control.NumericEditField
        Watershed                  matlab.ui.control.Button
        VolumehistButton           matlab.ui.control.Button
        ClosefilterButton          matlab.ui.control.Button
        FilterOutliersButton       matlab.ui.control.Button
        FillholesButton            matlab.ui.control.Button
        ImagehistogramButton       matlab.ui.control.Button
        SmoothxyEditFieldLabel     matlab.ui.control.Label
        SmoothxyEditField          matlab.ui.control.NumericEditField
        HdepthEditFieldLabel       matlab.ui.control.Label
        HdepthEditField            matlab.ui.control.NumericEditField
        FilterVolsButton           matlab.ui.control.Button
        maxvolumeEditFieldLabel    matlab.ui.control.Label
        WaterShedMaxVol            matlab.ui.control.NumericEditField
        SegmentSpotsTab            matlab.ui.container.Tab
        PresmoothButton            matlab.ui.control.Button
        xyEditField_3Label_2       matlab.ui.control.Label
        mean_filter_xy_value_2     matlab.ui.control.NumericEditField
        zLabel_2                   matlab.ui.control.Label
        mean_filter_xy_value_3     matlab.ui.control.NumericEditField
        SublocalbackgroundButton   matlab.ui.control.Button
        LoadcellmaskButton         matlab.ui.control.Button
        LogfilterButton            matlab.ui.control.Button
        ThresholdingButtonGroup_2  matlab.ui.container.ButtonGroup
        SimpleThresh_value_2       matlab.ui.control.NumericEditField
        Percentile_value_2         matlab.ui.control.NumericEditField
        LocalPercentile_value_2    matlab.ui.control.NumericEditField
        SimpleButton_2             matlab.ui.control.Button
        PercentileButton_2         matlab.ui.control.Button
        MaskButton                 matlab.ui.control.Button
        MergeduplicatesButton      matlab.ui.control.Button
        FiltersizesButton          matlab.ui.control.Button
        Percentile_value_3         matlab.ui.control.NumericEditField
        LocalPercentile_value_3    matlab.ui.control.NumericEditField
        TrackCellsTab              matlab.ui.container.Tab
        DispaylayerButtonGroup     matlab.ui.container.ButtonGroup
        ImageButton                matlab.ui.control.ToggleButton
        FilteredButton             matlab.ui.control.ToggleButton
        BWButton                   matlab.ui.control.ToggleButton
        SaveParametersButton       matlab.ui.control.Button
        CurrentStepsListBoxLabel   matlab.ui.control.Label
        CurrentStepsListBox        matlab.ui.control.ListBox
        BackButton                 matlab.ui.control.Button
        ForwardButton              matlab.ui.control.Button
        FrameEditFieldLabel        matlab.ui.control.Label
        FrameEditField             matlab.ui.control.NumericEditField
        ButtonGroup                matlab.ui.container.ButtonGroup
        DButton                    matlab.ui.control.ToggleButton
        DButton_2                  matlab.ui.control.ToggleButton
        z_range_low                matlab.ui.control.NumericEditField
        z_range_high               matlab.ui.control.NumericEditField
        RESETButton                matlab.ui.control.Button
        ChannelEditFieldLabel      matlab.ui.control.Label
        ChannelEditField           matlab.ui.control.NumericEditField
    end

    
    properties (Access = public )
        reader
        image_bits
        channel
        Image
        Filtered % filtered image
        BW% current binarized image
        stats
        curr_z
        curr_t
        obj % track_analyzer object
        %update_img=@update_img
        %update_stk=@update_stack
        toolboxApp
        view_layer
        img_dim
        seg_params=struct();
        seg_steps
    end
    
    methods (Access = public)
        
        function update_image(app)
            figure(10);
            imshow3D(app.(app.view_layer));
        end
        function update_image_filter(app)
            figure(10)
            imshow3D_filter(app.(app.view_layer));
        end
        
        function update_stack(app)
            
            app.Image   = get_stack(app.reader, app.curr_t, app.channel);
            
            if app.img_dim == 2 & length(size(app.Image))==3
                z_vals = [app.z_range_low.Value:app.z_range_high.Value];
                app.Image = max(app.Image(:,:,z_vals),[],3);
            end
            
            app.Filtered=[];
            app.BW      =[];
            app.view_layer='Image';
            update_image(app)
            %p=1
        end
   
        function return_str = channel_str(app)
        
            return_str = ['channel_', pad(num2str(app.channel), 2, 'left', '0')];
            
        end
        
    end
    
    methods (Access = private)
        
        function add_step(app, this_step)
            
            if isempty(app.seg_steps(1).type)
                app.seg_steps(1).type=this_step;
            else
                %Check if previous step is same. If not, then append. 
                previous_step = app.seg_steps(end).type;
                
                if strcmp(previous_step, this_step)
                    app.seg_steps(end).type = this_step;
                else
                    app.seg_steps(end+1).type = this_step;
                end
            end
            
            %Update text box. 
            update_seg_steps(app)

        end
        
        function initialize_seg_steps(app)
%             steps={'subtract_background',
%             'clahe',
%             'diff_gradients',
%             'thresholding',
%             'filter_objects',
%             'close_filter',
%             'filter_outliers',
%             'fill_holes',
%             'filter_objects',
%             'watershed',
%             'merge',
%             'filter_rg'};
        
                app.seg_steps = struct('type',[],'value',[]);
            
%             % Fill in steps. 
%             for i = 1:length(out_steps)
%             
%                 out_steps(i).type=steps{i};
%                 out_steps(i).value=0;
%             
%             end
        end
            
        function initialize_seg_params(app)
            
            % First load default parameters. 
            app.seg_params.(app.channel_str).cells=app.default_cell_params;

            % Now check if existing parameters
            if isfield(app.obj.exp_info,'params')
                if isfield(app.obj.exp_info.params,app.channel_str)
                    % add parameters (one-by-one in case some aren't defined. 
                    for p = fieldnames(app.obj.exp_info.params.(app.channel_str).cells)'
                        app.seg_params.(app.channel_str).cells.(p{1})=app.obj.exp_info.params.(app.channel_str).cells.(p{1});
                    end
                end
            end
            
            % Set gui fields. 
            app.background_value.Value = app.seg_params.(app.channel_str).cells.bg;
            
            app.TileSizeEditField.Value = app.seg_params.(app.channel_str).cells.tile_size;
            app.ClipLimitEditField.Value = app.seg_params.(app.channel_str).cells.ClipLimit;
            
            app.AlphaEditField.Value = app.seg_params.(app.channel_str).cells.alpha;
            app.BetaEditField.Value= app.seg_params.(app.channel_str).cells.beta;
            app.GammaEditField.Value= app.seg_params.(app.channel_str).cells.gamma;
            app.DeltaEditField.Value=app.seg_params.(app.channel_str).cells.delta;
            app.EpsilonEditField.Value= app.seg_params.(app.channel_str).cells.epsilon;
            app.xyEditField_2.Value = app.seg_params.(app.channel_str).cells.sigmagradient(1);
            app.zEditField_2.Value =  app.seg_params.(app.channel_str).cells.sigmagradient(3);
            
            app.xyEditField.Value= app.seg_params.(app.channel_str).cells.median_filter(1);
            app.zEditField.Value = app.seg_params.(app.channel_str).cells.median_filter(3);

            app.SimpleThresh_value.Value=app.seg_params.(app.channel_str).cells.simple_threshold(app.curr_t);
            app.Percentile_value.Value= app.seg_params.(app.channel_str).cells.percentile(app.curr_t);
            app.LocalPercentile_value.Value=app.seg_params.(app.channel_str).cells.percentile(app.curr_t);
            % Now tack on a filtering step. 
            app.AbsMinVol.Value= app.seg_params.(app.channel_str).cells.AbsMinVol;
            app.AbsMaxVol.Value=app.seg_params.(app.channel_str).cells.AbsMaxVol;
            app.imclose_radius_value.Value=app.seg_params.(app.channel_str).cells.imclose_r;
            app.SensitivityEditField.Value=app.seg_params.(app.channel_str).cells.MeanFilterSensitivity;
            app.mean_filter_xy_value.Value=app.seg_params.(app.channel_str).cells.MeanFilterNeighborhood(1);
            try
                app.mean_filter_z_value.Value =app.seg_params.(app.channel_str).cells.MeanFilterNeighborhood(3); 
            catch
                app.mean_filter_z_value.Value=0;
            end

            app.outlier_threshold_value.Value=app.seg_params.(app.channel_str).cells.OutlierThreshold;
            app.hmindepthEditField.Value=app.seg_params.(app.channel_str).cells.h_min_depth;
            app.mergedistEditField.Value=app.seg_params.(app.channel_str).cells.merge_dist;

            app.SmoothxyEditField.Value=app.seg_params.(app.channel_str).cells.I_sm_sigma;
            app.HdepthEditField.Value=app.seg_params.(app.channel_str).cells.Hdepth;
            app.WaterShedMaxVol.Value = app.seg_params.(app.channel_str).cells.WaterShedMaxVol;
            
        end
        
        function out_params = default_cell_params(app)
            % Set gui fields. 
            out_params.bg=0;
            
            out_params.tile_size=25;
            out_params.ClipLimit=0.05;
            
            out_params.alpha=0;
            out_params.beta=0.1;
            out_params.gamma=8;
            out_params.delta=1;
            out_params.epsilon=0;
            out_params.sigmagradient=[5,5,2];
            
            out_params.median_filter = [7,7,1];
            
            out_params.simple_threshold = 100;
            out_params.percentile= 78*ones(1,app.obj.exp_info.t_frames);
            out_params.I_sm_sigma=5;
            out_params.Hdepth=1000;
            % Now tack on a filtering step. 
            out_params.AbsMinVol=600;
            out_params.AbsMaxVol=3000;
            out_params.imclose_r=5;
            out_params.MeanFilterSensitivity=0.4;
            out_params.MeanFilterNeighborhood=[7,7,8];
            out_params.OutlierThreshold=60000;
            out_params.h_min_depth=1;
            out_params.merge_dist=20;
            out_params.WaterShedMaxVol = 3000;
            
        end
        
        % Update seg_steps. 
        function update_seg_steps(app)
        
            if ~isempty(app.seg_steps(1).type)
                
                if length(app.seg_steps)==1
                    app.CurrentStepsListBox.Items=cellstr(app.seg_steps.type);
                else
                    app.CurrentStepsListBox.Items=cellstr(strvcat(app.seg_steps.type));
                end
                
            else
                app.CurrentStepsListBox.Items={};
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj, step)
            
            if nargin < 3
                step.use_max_p=0;
            end
            % Configure image axes
            %app.ImageAxes.Visible = 'off';
            %app.ImageAxes.Colormap = gray(256);
            %axis(app.ImageAxes, 'image');
            drawnow
            
            %Which image file?
            if step.use_max_p
                img_file_name=obj.exp_info.max_p_img;
            else
                img_file_name=obj.exp_info.img_file;
            end

            % initialize time and channels. 
            app.curr_t=1;
            app.channel=1;
            app.curr_z=1;
            app.obj=obj;
            % Add image reader to app
            app.reader = bfGetReader(img_file_name);
            app.image_bits=app.reader.getBitsPerPixel;   

            app.view_layer='Image';
            %Initialize image
            update_stack(app);
            app.img_dim = length(size(app.Image));
            
            switch app.img_dim
                case 2
                    app.ButtonGroup.SelectedObject=app.DButton;
                    app.z_range_high=1;
                case 3
                    app.ButtonGroup.SelectedObject=app.DButton_2;
                    app.z_range_high.Value=obj.exp_info.z_planes;
            end
            
            app.initialize_seg_params();
            app.initialize_seg_steps();
            % Initialize step box. 
            
            % Call diaglog app
            %app.toolboxApp=tool_box(app);
        end

        % Callback function
        function t_change(app, event)
            app.curr_t = app.TimeSlider.Value;
            update_stack(app)
        end

        % Selection changed function: DispaylayerButtonGroup
        function layer_change(app, event)
            value = app.DispaylayerButtonGroup.SelectedObject.Text;
            
            % Figure out which layer to display. 
            switch value
                
                case 'Image'
                    
                    app.view_layer='Image';
                    % Set current layer. 
                    app.update_image();
                case 'Filtered'
                    % Check if filtered exists yet. 
                    if isempty(app.Filtered)
                        app.view_layer='Image';
                    else
                        app.view_layer='Filtered';
                    end
                    app.update_image_filter
                case 'BW'      
                    % Check if BW exists yet. 
                    if isempty(app.BW)
                        app.view_layer='Image';
                    else
                        app.view_layer='BW';
                    end   
                    % Set current layer. 
                    app.update_image();
            end

            
        end

        % Button pushed function: RESETButton
        function reset_button(app, event)
            app.initialize_seg_steps;
            app.Filtered=[];
            app.BW=[];
            app.update_stack();
        end

        % Button pushed function: MeanfilterButton
        function mean_filter_button(app, event)
            app.seg_params.(app.channel_str).cells.MeanFilterSensitivity=app.SensitivityEditField.Value;
            % Check dimensions. 
            switch app.img_dim
                
                case 2
                    app.seg_params.(app.channel_str).cells.MeanFilterNeighborhood= app.mean_filter_xy_value.Value;
                    
                case 3
                    app.seg_params.(app.channel_str).cells.MeanFilterNeighborhood= [app.mean_filter_xy_value.Value,app.mean_filter_xy_value.Value,app.mean_filter_z_value.Value];

            end
            app.Image = mean_filter(app.Image,app.seg_params.(app.channel_str).cells.MeanFilterSensitivity, ...
            app.seg_params.(app.channel_str).cells.MeanFilterNeighborhood,app.image_bits);
            
            app.add_step('mean_filter');
            app.update_image();
        end

        % Button pushed function: SubbackgroundButton
        function subtract_background_button(app, event)
            % Subtract background. 
            app.seg_params.(app.channel_str).cells.bg = app.background_value.Value;
            app.Image = subtract_background(app.Image,app.seg_params.(app.channel_str).cells.bg);
            app.update_image();
            
            % Record step
            app.add_step('subtract_background');

        end

        % Button pushed function: claheButton
        function clahe_button(app, event)
            app.seg_params.(app.channel_str).cells.tile_size=app.TileSizeEditField.Value;
            app.seg_params.(app.channel_str).cells.ClipLimit=app.ClipLimitEditField.Value;
            
            app.Image = clahe(app.Image,app.seg_params.(app.channel_str).cells.tile_size,app.seg_params.(app.channel_str).cells.ClipLimit);
            app.update_image;
            % Record step
            app.add_step('clahe');

        end

        % Button pushed function: gradientimageButton
        function gradient_button(app, event)
            % Fix kappa?
            app.seg_params.(app.channel_str).cells.kappa1=10;
            app.seg_params.(app.channel_str).cells.kappa2=0;
            app.seg_params.(app.channel_str).cells.option=1;
            app.seg_params.(app.channel_str).cells.diffuse_iterations=5;
            % Get parameters. 
            app.seg_params.(app.channel_str).cells.alpha=app.AlphaEditField.Value;
            app.seg_params.(app.channel_str).cells.beta=app.BetaEditField.Value;
            app.seg_params.(app.channel_str).cells.gamma=app.GammaEditField.Value;
            app.seg_params.(app.channel_str).cells.delta=app.DeltaEditField.Value;
            app.seg_params.(app.channel_str).cells.epsilon=app.EpsilonEditField.Value;
            app.seg_params.(app.channel_str).cells.sigmagradient = [app.xyEditField_2.Value,app.xyEditField_2.Value,app.zEditField_2.Value];
            app.seg_params.(app.channel_str).cells.median_filter = [app.xyEditField.Value,app.xyEditField.Value,app.zEditField.Value];
            
            d = uiprogressdlg(app.UIFigure,'Title','Computing gradients',...
                'Indeterminate','on');
        
            app.Filtered = diff_gradients(app.Image, app.seg_params.(app.channel_str).cells);
            app.view_layer = 'Filtered';
            app.update_image_filter;

            % Record step. 
            app.add_step('diff_gradients');
     
            % close the dialog box
            close(d);
        end

        % Callback function: LocalButton, PercentileButton, 
        % SimpleButton, ThresholdingButtonGroup
        function threshold_button(app, event)
            selectedButton = event.Source.Text;
            
            % Check if last step was thresholding. 
            types={'simple_thresholding','hist_thresholding','adaptive_thresholding'};
            % Check if any previous steps were thresholding.
            next=length(app.seg_steps)+1;
            for s = 1:length(app.seg_steps)
                if any( strcmp(types, app.seg_steps(s).type ))
                    next = s;
                    app.seg_steps = app.seg_steps(1:s-1);
                    break
                end
            end
            

            switch selectedButton
                
                case 'Simple'
                    
                    app.seg_params.(app.channel_str).cells.simple_threshold(app.curr_t) = app.SimpleThresh_value.Value;
                    app.BW = simple_thresholding(app.Filtered, app.seg_params.(app.channel_str).cells.simple_threshold );
                    app.seg_steps(next).type='simple_thresholding';
                    
                case 'Percentile'
                    
                    app.seg_params.(app.channel_str).cells.percentile(app.curr_t) = app.Percentile_value.Value;
                    app.BW = hist_thresholding(app.Filtered, app.seg_params.(app.channel_str).cells.percentile(app.curr_t) );
                    app.seg_steps(next).type='hist_thresholding';
                    
                case 'Local'
                    
                    app.seg_params.(app.channel_str).cells.percentile(app.curr_t) = app.LocalPercentile_value.Value;
                    app.seg_params.(app.channel_str).cells.I_sm_sigma=app.SmoothxyEditField.Value;
                    app.seg_params.(app.channel_str).cells.Hdepth = app.HdepthEditField.Value;
                    
                    params=struct();
                    params.percentile=app.seg_params.(app.channel_str).cells.percentile(app.curr_t);
                    params.I_sm_sigma=app.seg_params.(app.channel_str).cells.I_sm_sigma;
                    params.Hdepth=app.seg_params.(app.channel_str).cells.Hdepth;
                    params.AbsMinVol=app.seg_params.(app.channel_str).cells.AbsMinVol;
                    params.AbsMaxVol=app.seg_params.(app.channel_str).cells.AbsMaxVol;
                    app.BW = adaptive_thresholding(app.Filtered,app.Filtered, params);
                    app.seg_steps(next).type = 'adaptive_thresholding';
                    
                    
            end
            
            % Now tack on a filtering step. 
            app.seg_params.(app.channel_str).cells.AbsMinVol = app.AbsMinVol.Value;
            app.seg_params.(app.channel_str).cells.AbsMaxVol = app.AbsMaxVol.Value;
            app.BW = filter_objects(app.BW,app.seg_params.(app.channel_str).cells.AbsMinVol);
            app.stats = regionprops(app.BW,'Centroid','Area','PixelList','PixelIdxList');
            update_seg_steps(app)
            app.view_layer='BW';
            app.DispaylayerButtonGroup.SelectedObject=app.BWButton;
            app.update_image;

        end

        % Button pushed function: ClosefilterButton
        function close_button(app, event)
            app.seg_params.(app.channel_str).cells.imclose_r = app.imclose_radius_value.Value;
             d = uiprogressdlg(app.UIFigure,'Title','ImClose filter',...
                'Indeterminate','on');
        
            app.BW = close_filter(app.BW, app.seg_params.(app.channel_str).cells.imclose_r);
            
            app.add_step('close_filter');
            app.update_image;
            close(d);
            
        end

        % Button pushed function: FilterOutliersButton
        function filter_outliers_button(app, event)
            app.seg_params.(app.channel_str).cells.OutlierThreshold = app.outlier_threshold_value.Value;
            % Check dimensions. 
            switch app.img_dim
                
                case 2
                    app.seg_params.(app.channel_str).cells.MeanFilterNeighborhood= app.mean_filter_xy_value.Value;
                    
                case 3
                    app.seg_params.(app.channel_str).cells.MeanFilterNeighborhood= [app.mean_filter_xy_value.Value,app.mean_filter_xy_value.Value,app.mean_filter_z_value.Value];

            end           
            app.BW = filter_outliers(app.Image,app.BW,app.seg_params.(app.channel_str).cells.OutlierThreshold,app.seg_params.(app.channel_str).cells.MeanFilterNeighborhood);
            app.add_step('filter_outliers');
            app.update_image;

        end

        % Button pushed function: FillholesButton
        function fill_holes_button(app, event)
                        
            app.BW = fill_holes(app.BW);
            app.seg_params.(app.channel_str).cells.AbsMinVol = app.AbsMinVol.Value;

            app.BW = filter_objects(app.BW, app.seg_params.(app.channel_str).cells.AbsMinVol);
            app.add_step('fill_holes');
            app.update_image;

        end

        % Button pushed function: Watershed
        function watershed_button(app, event)
            app.seg_params.(app.channel_str).cells.nconnBW = 18;
            app.seg_params.(app.channel_str).cells.h_min_depth=app.hmindepthEditField.Value;
            app.seg_params.(app.channel_str).cells.h_min_conn=6;
            app.seg_params.(app.channel_str).cells.z_effect = 2;
            app.seg_params.(app.channel_str).cells.WaterShedMaxVol = app.WaterShedMaxVol.Value;
            
            d = uiprogressdlg(app.UIFigure,'Title','Computing watershed',...
                'Indeterminate','on');
        
            
            [app.BW,app.stats] = watershed_objects( ...
                app.BW, app.seg_params.(app.channel_str).cells.nconnBW,app.seg_params.(app.channel_str).cells.WaterShedMaxVol, ...
                app.seg_params.(app.channel_str).cells.h_min_depth, app.seg_params.(app.channel_str).cells.h_min_conn, ...
                app.seg_params.(app.channel_str).cells.z_effect,app.obj.exp_info.pixel_size);
            
            
            app.add_step('watershed');
            app.update_image;
            close(d);
        end

        % Value changed function: MergerButton
        function merger_button(app, event)
            app.seg_params.(app.channel_str).cells.merge_dist = app.mergedistEditField.Value;
            app.stats = merge_objects(app.stats,app.seg_params.(app.channel_str).cells.merge_dist, app.seg_params.(app.channel_str).cells.AbsMaxVol);
            
            app.add_step('merge_objects');
            
        end

        % Button pushed function: SaveParametersButton
        function save_params_button(app, event)
            
            % Update obj params. 
            app.obj.exp_info.params.(app.channel_str).cells=app.seg_params.(app.channel_str).cells;
            app.obj.exp_info.steps.(app.channel_str).cells =app.seg_steps;
            
            % Interpolate percentiles if multiple saved values.
            frames=find(app.seg_params.(app.channel_str).cells.percentile);
            N=length(app.obj.exp_info.time_series);
            if length(frames) > 1
                pcs=interp1(frames,app.seg_params.(app.channel_str).cells.percentile(frames),1:N, 'pchip');
                app.obj.exp_info.params.(app.channel_str).cells.percentile=pcs;                 
            else
                app.obj.exp_info.params.(app.channel_str).cells.percentile(1:N) = app.obj.exp_info.params.(app.channel_str).cells.percentile;
            end
            app.obj.exp_info.params.(app.channel_str).cells.seg_dims=app.img_dim;
            if app.img_dim==2
                app.obj.exp_info.params.(app.channel_str).cells.z_range=[app.z_range_low.Value:app.z_range_high.Value];
            end
            app.obj.save;
            
            
            % Also export parameters to dated file. 
            FileName=[app.obj.exp_info.nuc_seg_dir,'params_',datestr(now, 'HH:MM:SS-dd-mmm-yyyy'),'.mat'];
            params = app.obj.exp_info.params.(app.channel_str).cells;
            steps = app.seg_steps;
            save(FileName,'params','steps');
            
            
        end

        % Button pushed function: VolumehistButton
        function volume_hist_function(app, event)
            
            % Get stats. 
            vols = [app.stats.Area];
            figure(12);
            std_figure_settings
            hist(vols);
            
        end

        % Value changed function: ChannelEditField
        function change_channel(app, event)
            app.channel=app.ChannelEditField.Value;
            app.update_stack;
            app.initialize_seg_steps
            app.initialize_seg_params
            
        end

        % Button pushed function: ImagehistogramButton
        function image_hist_button(app, event)
            
            %Diplay percentile histogram. 
            figure(13)
            pcs=linspace(0,100,200);
            vals=prctile(app.(app.view_layer)(:),pcs);
            std_figure_settings
            plot(pcs,vals)
        end

        % Value changed function: FrameEditField
        function frame_value_box(app, event)
            value = app.FrameEditField.Value;
            app.curr_t=min(length(app.obj.exp_info.time_series),round(value));
            app.FrameEditField.Value=app.curr_t;
            app.update_stack
            app.initialize_seg_steps;
        end

        % Button pushed function: BackButton
        function back_frame_button(app, event)
            app.curr_t=max(1,app.curr_t-1);
            app.FrameEditField.Value=app.curr_t;
            app.update_stack
            app.initialize_seg_steps;
        end

        % Button pushed function: ForwardButton
        function forward_frame_button(app, event)
            app.curr_t=min(length(app.obj.exp_info.time_series),app.curr_t+1);
            app.FrameEditField.Value=app.curr_t;
            app.update_stack;
            app.initialize_seg_steps;
        end

        % Value changed function: AbsMinVol
        function abs_min_change(app, event)
            value = app.AbsMinVol.Value;
            app.seg_params.(app.channel_str).cells.AbsMinVol=value;
        end

        % Value changed function: AbsMaxVol
        function abs_max_change(app, event)
            value = app.AbsMaxVol.Value;
            app.seg_params.(app.channel_str).cells.AbsMaxVol=value;
        end

        % Selection changed function: ButtonGroup
        function dimension_button_change(app, event)
            selectedButton = app.ButtonGroup.SelectedObject.Text;
            
            switch selectedButton
                
                case '2D'
                    
                    app.img_dim=2;
                                        
                case '3D'
                    
                    app.img_dim=3;
                                    
            end
        end

        % Button pushed function: FilterVolsButton
        function filter_vols_button(app, event)
            [app.BW, app.stats] = filter_objects(logical(app.BW), app.AbsMinVol.Value, app.AbsMaxVol.Value);
            app.update_image;
            app.add_step('filter_objects')
        end

        % Selection change function: TabGroup
        function tab_change_button(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            app.tab = selectedTab.String;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @tab_change_button, true);
            app.SegmentCellsTab = uitab(app.TabGroup);
            app.SegmentCellsTab.Title = 'Segment Cells';
            app.ThresholdingButtonGroup = uibuttongroup(app.SegmentCellsTab);
            app.ThresholdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @threshold_button, true);
            app.ThresholdingButtonGroup.Title = 'Thresholding';
            app.SimpleThresh_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.Percentile_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.LocalPercentile_value = uieditfield(app.ThresholdingButtonGroup, 'numeric');
            app.SimpleButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.SimpleButton.ButtonPushedFcn = createCallbackFcn(app, @threshold_button, true);
            app.SimpleButton.Text = 'Simple';
            app.PercentileButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.PercentileButton.ButtonPushedFcn = createCallbackFcn(app, @threshold_button, true);
            app.PercentileButton.Text = 'Percentile';
            app.LocalButton = uibutton(app.ThresholdingButtonGroup, 'push');
            app.LocalButton.ButtonPushedFcn = createCallbackFcn(app, @threshold_button, true);
            app.LocalButton.Text = 'Local';
            app.AbsMaxVol = uieditfield(app.SegmentCellsTab, 'numeric');
            app.AbsMaxVol.ValueChangedFcn = createCallbackFcn(app, @abs_max_change, true);
            app.VolumesEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.VolumesEditFieldLabel.Text = 'Volumes';
            app.AbsMinVol = uieditfield(app.SegmentCellsTab, 'numeric');
            app.AbsMinVol.ValueChangedFcn = createCallbackFcn(app, @abs_min_change, true);
            app.background_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.TileSizeEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.TileSizeEditFieldLabel.Text = 'Tile Size';
            app.TileSizeEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.ClipLimitEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.ClipLimitEditFieldLabel.Text = 'Clip Limit';
            app.ClipLimitEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.AlphaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.EpsilonEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.EpsilonEditFieldLabel.Text = 'Epsilon';
            app.EpsilonEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SubBackChkbox = uicheckbox(app.SegmentCellsTab);
            app.SubBackChkbox.Text = {''; ''};
            app.CLAHEChkbox = uicheckbox(app.SegmentCellsTab);
            app.CLAHEChkbox.Text = {''; ''};
            app.CheckBox = uicheckbox(app.SegmentCellsTab);
            app.CheckBox.Text = {''; ''};
            app.DeltaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.DeltaEditFieldLabel.Text = 'Delta';
            app.DeltaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SubbackgroundButton = uibutton(app.SegmentCellsTab, 'push');
            app.SubbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @subtract_background_button, true);
            app.SubbackgroundButton.Text = 'Sub background';
            app.claheButton = uibutton(app.SegmentCellsTab, 'push');
            app.claheButton.ButtonPushedFcn = createCallbackFcn(app, @clahe_button, true);
            app.claheButton.Text = 'clahe';
            app.gradientimageButton = uibutton(app.SegmentCellsTab, 'push');
            app.gradientimageButton.ButtonPushedFcn = createCallbackFcn(app, @gradient_button, true);
            app.gradientimageButton.Text = 'gradient image';
            app.GammaEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MedianFilterPanel = uipanel(app.SegmentCellsTab);
            app.MedianFilterPanel.Title = 'Median Filter';
            app.xyEditFieldLabel = uilabel(app.MedianFilterPanel);
            app.xyEditFieldLabel.Text = 'xy';
            app.xyEditField = uieditfield(app.MedianFilterPanel, 'numeric');
            app.zEditFieldLabel = uilabel(app.MedianFilterPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.MedianFilterPanel, 'numeric');
            app.SigmaGradientPanel = uipanel(app.SegmentCellsTab);
            app.SigmaGradientPanel.Title = 'Sigma Gradient';
            app.xyEditField_2Label = uilabel(app.SigmaGradientPanel);
            app.xyEditField_2Label.Text = 'xy';
            app.xyEditField_2 = uieditfield(app.SigmaGradientPanel, 'numeric');
            app.zEditField_2Label = uilabel(app.SigmaGradientPanel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.SigmaGradientPanel, 'numeric');
            app.imclose_radius_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MeanfilterButton = uibutton(app.SegmentCellsTab, 'push');
            app.MeanfilterButton.ButtonPushedFcn = createCallbackFcn(app, @mean_filter_button, true);
            app.MeanfilterButton.Text = 'Mean filter';
            app.SensitivityEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.outlier_threshold_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.hmindepthEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.hmindepthEditFieldLabel.Text = 'hmin depth';
            app.hmindepthEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.MergerButton = uibutton(app.SegmentCellsTab, 'state');
            app.MergerButton.ValueChangedFcn = createCallbackFcn(app, @merger_button, true);
            app.MergerButton.Text = 'Merger';
            app.mergedistEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.mergedistEditFieldLabel.Text = 'merge dist';
            app.mergedistEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.xyEditField_3Label = uilabel(app.SegmentCellsTab);
            app.xyEditField_3Label.Text = 'xy';
            app.mean_filter_xy_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.zLabel = uilabel(app.SegmentCellsTab);
            app.zLabel.Text = 'z';
            app.mean_filter_z_value = uieditfield(app.SegmentCellsTab, 'numeric');
            app.Watershed = uibutton(app.SegmentCellsTab, 'push');
            app.Watershed.ButtonPushedFcn = createCallbackFcn(app, @watershed_button, true);
            app.Watershed.Text = {'Watershed'; ''};
            app.VolumehistButton = uibutton(app.SegmentCellsTab, 'push');
            app.VolumehistButton.ButtonPushedFcn = createCallbackFcn(app, @volume_hist_function, true);
            app.VolumehistButton.Text = 'Volume hist';
            app.ClosefilterButton = uibutton(app.SegmentCellsTab, 'push');
            app.ClosefilterButton.ButtonPushedFcn = createCallbackFcn(app, @close_button, true);
            app.ClosefilterButton.Text = 'Close filter';
            app.FilterOutliersButton = uibutton(app.SegmentCellsTab, 'push');
            app.FilterOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @filter_outliers_button, true);
            app.FilterOutliersButton.Text = 'Filter Outliers';
            app.FillholesButton = uibutton(app.SegmentCellsTab, 'push');
            app.FillholesButton.ButtonPushedFcn = createCallbackFcn(app, @fill_holes_button, true);
            app.FillholesButton.Text = 'Fill holes';
            app.ImagehistogramButton = uibutton(app.SegmentCellsTab, 'push');
            app.ImagehistogramButton.ButtonPushedFcn = createCallbackFcn(app, @image_hist_button, true);
            app.ImagehistogramButton.Text = 'Image histogram';
            app.SmoothxyEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.SmoothxyEditFieldLabel.Text = 'Smooth xy';
            app.SmoothxyEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.HdepthEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.HdepthEditFieldLabel.Text = 'Hdepth';
            app.HdepthEditField = uieditfield(app.SegmentCellsTab, 'numeric');
            app.FilterVolsButton = uibutton(app.SegmentCellsTab, 'push');
            app.FilterVolsButton.ButtonPushedFcn = createCallbackFcn(app, @filter_vols_button, true);
            app.FilterVolsButton.Text = 'Filter Vols';
            app.maxvolumeEditFieldLabel = uilabel(app.SegmentCellsTab);
            app.maxvolumeEditFieldLabel.Text = 'max volume';
            app.WaterShedMaxVol = uieditfield(app.SegmentCellsTab, 'numeric');
            app.SegmentSpotsTab = uitab(app.TabGroup);
            app.SegmentSpotsTab.Title = 'Segment Spots';
            app.PresmoothButton = uibutton(app.SegmentSpotsTab, 'push');
            app.PresmoothButton.Text = 'Pre-smooth';
            app.xyEditField_3Label_2 = uilabel(app.SegmentSpotsTab);
            app.xyEditField_3Label_2.Text = 'xy';
            app.mean_filter_xy_value_2 = uieditfield(app.SegmentSpotsTab, 'numeric');
            app.zLabel_2 = uilabel(app.SegmentSpotsTab);
            app.zLabel_2.Text = 'z';
            app.mean_filter_xy_value_3 = uieditfield(app.SegmentSpotsTab, 'numeric');
            app.SublocalbackgroundButton = uibutton(app.SegmentSpotsTab, 'push');
            app.SublocalbackgroundButton.Text = 'Sub local background';
            app.LoadcellmaskButton = uibutton(app.SegmentSpotsTab, 'push');
            app.LoadcellmaskButton.Text = 'Load cell mask';
            app.LogfilterButton = uibutton(app.SegmentSpotsTab, 'push');
            app.LogfilterButton.Text = 'Log filter';
            app.ThresholdingButtonGroup_2 = uibuttongroup(app.SegmentSpotsTab);
            app.ThresholdingButtonGroup_2.Title = 'Thresholding';
            app.SimpleThresh_value_2 = uieditfield(app.ThresholdingButtonGroup_2, 'numeric');
            app.Percentile_value_2 = uieditfield(app.ThresholdingButtonGroup_2, 'numeric');
            app.LocalPercentile_value_2 = uieditfield(app.ThresholdingButtonGroup_2, 'numeric');
            app.SimpleButton_2 = uibutton(app.ThresholdingButtonGroup_2, 'push');
            app.SimpleButton_2.Text = 'Simple';
            app.PercentileButton_2 = uibutton(app.ThresholdingButtonGroup_2, 'push');
            app.PercentileButton_2.Text = 'Percentile';
            app.MaskButton = uibutton(app.SegmentSpotsTab, 'push');
            app.MaskButton.Text = 'Mask';
            app.MergeduplicatesButton = uibutton(app.SegmentSpotsTab, 'push');
            app.MergeduplicatesButton.Text = 'Merge duplicates';
            app.FiltersizesButton = uibutton(app.SegmentSpotsTab, 'push');
            app.FiltersizesButton.Text = 'Filter sizes';
            app.Percentile_value_3 = uieditfield(app.SegmentSpotsTab, 'numeric');
            app.LocalPercentile_value_3 = uieditfield(app.SegmentSpotsTab, 'numeric');
            app.TrackCellsTab = uitab(app.TabGroup);
            app.TrackCellsTab.Title = 'Track Cells';
            app.DispaylayerButtonGroup = uibuttongroup(app.UIFigure);
            app.DispaylayerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @layer_change, true);
            app.DispaylayerButtonGroup.Title = 'Dispay layer';
            app.ImageButton = uitogglebutton(app.DispaylayerButtonGroup);
            app.ImageButton.Text = 'Image';
            app.ImageButton.Value = true;
            app.FilteredButton = uitogglebutton(app.DispaylayerButtonGroup);
            app.FilteredButton.Text = 'Filtered';
            app.BWButton = uitogglebutton(app.DispaylayerButtonGroup);
            app.BWButton.Text = 'BW';
            app.SaveParametersButton = uibutton(app.UIFigure, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @save_params_button, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.CurrentStepsListBoxLabel = uilabel(app.UIFigure);
            app.CurrentStepsListBoxLabel.Text = 'Current Steps';
            app.CurrentStepsListBox = uilistbox(app.UIFigure);
            app.CurrentStepsListBox.Items = {};
            app.CurrentStepsListBox.Value = {};
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @back_frame_button, true);
            app.BackButton.Text = 'Back';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @forward_frame_button, true);
            app.ForwardButton.Text = 'Forward';
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @frame_value_box, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dimension_button_change, true);
            app.DButton = uitogglebutton(app.ButtonGroup);
            app.DButton.Text = '2D';
            app.DButton.Value = true;
            app.DButton_2 = uitogglebutton(app.ButtonGroup);
            app.DButton_2.Text = '3D';
            app.z_range_low = uieditfield(app.UIFigure, 'numeric');
            app.z_range_low.Value = 1;
            app.z_range_high = uieditfield(app.UIFigure, 'numeric');
            app.z_range_high.Value = 10;
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @reset_button, true);
            app.RESETButton.Text = 'RESET';
            app.ChannelEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelEditField.Limits = [1 10];
            app.ChannelEditField.RoundFractionalValues = 'on';
            app.ChannelEditField.ValueChangedFcn = createCallbackFcn(app, @change_channel, true);
            app.ChannelEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end