classdef viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ZEditField               matlab.ui.control.NumericEditField
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        ThresholdCheckBox        matlab.ui.control.CheckBox
        ZSlider                  matlab.ui.control.Slider
        ZLabel                   matlab.ui.control.Label
        PercentileAxes           matlab.ui.control.UIAxes
        HistPlot                 matlab.ui.control.UIAxes
        imgAX                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img % Description
    end
    
    properties (Access = private)
        z_slices % Description
        initial_thresh % Description
        increment % Description
    end
    
    methods (Access = private)
        
        function [Rmn Rmx] = WL2R(app,W,L)
            Rmn = L - (W/2);
            Rmx = L + (W/2);
            if (Rmn >= Rmx)
                Rmx = Rmn + 1;
            end
        end
        
        function mouseScroll(app, ~, eventdata)
            UPDN = eventdata.VerticalScrollCount;
            Z = app.ZSlider.Value;
            Z = Z - UPDN;
            if (Z < 1)
                Z = 1;
            elseif (Z > app.ZSlider.Limits(2))
                Z = app.ZSlider.Limits(2);
            end
            app.ZSlider.Value=Z;
            app.update_image;
        end
    end
    
    methods (Access = public)
        
        % When the whole stack is updated, update  histogram. 
        function update_stack(app,img)

            app.img = img;
            app.ZSlider.Limits = [1,size(app.img,3)];
            app.ZSlider.Value = round( size(app.img,3)/2);

            % update histogram plot. 
            H = histogram(app.HistPlot,app.img(:),100);
            H.LineWidth = 2;
            app.HistPlot.YScale='log';

            % update percentile plot. 
            pcs=linspace(0,100,200);
            vals=prctile(app.img(:),pcs);
            plot(app.PercentileAxes,pcs,vals)

            % update image. 
            app.update_image;

        end


        function update_image(app)
            
            %Remove any existing contours. 
            H = findobj(app.imgAX,'Type','line');
            delete(H);

            % Display image. 
            MinV = 0;
            MaxV = max(app.img(:));
            LevV = (double( MaxV) + double(MinV)) / 2;
            Win = double(MaxV) - double(MinV);
            WLAdjCoe = (Win + 1)/1024;
            FineTuneC = [1 1/16];    % Regular/Fine-tune mode coefficients
            [Rmin Rmax] = app.WL2R(Win, LevV);
            imshow(app.img(:,:,app.ZSlider.Value),[Rmin Rmax],'Parent',app.imgAX);
            app.ZEditField.Value = app.ZSlider.Value;

            % Check if threshold box is checked. 
            if app.ThresholdCheckBox.Value
                %Get threshold
                thresh_val =app.ThresholdEditField.Value;
                this_plane = app.img(:,:,app.ZSlider.Value);
                %p_val = findp(app.img(:),thresh_val);
                %disp("percentile value: ",p_val)

                bw = this_plane >= thresh_val;
                B = bwboundaries(bw,'noholes');

                % Plot boundaries as lines. 
                hold(app.imgAX,"on")
                for i = 1:length(B)
                    plot(app.imgAX,B{i}(:,2),B{i}(:,1),'-r','linewidth',2);
                end
                hold(app.imgAX,"off")
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, varargin)

            % First get some info from the track_obj. 
            app.z_slices = size(img,3);  % number of slices
            app.ZSlider.Limits = [1,app.z_slices];
            app.ZSlider.Value = round(size(img,3)/2);
            
            % add image to app;
            app.img=img;
            vals = img(:);
            app.increment = 5* (max(vals)-min(vals))/100;

            % Update image. 
            app.update_image;

            %Setup some stuff. 
            app.UIFigure.WindowScrollWheelFcn=@app.mouseScroll;
            
            p = inputParser;
            p.addParameter('zval',@isinteger);
            p.addParameter('initial_thresh',prctile(vals,90),@isinteger);
            p.addParameter('filter_on',false,@islogical);
            p.parse(varargin{:})

            if isfield(p.Results,"zval")
                S = p.Results.zval;
            end
            app.initial_thresh = p.Results.initial_thresh;

            
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            app.ZSlider.Value = round(app.ZSlider.Value);
            app.ZEditField.Value = app.ZSlider.Value;
            app.update_image;
        end

        % Value changed function: ThresholdEditField
        function threshold_value_change(app, event)
            app.update_image;
            
            %Update histogram graphic with threshold.  
            Y = app.HistPlot.YLim;
            threshold = app.ThresholdEditField.Value;
            delete(findobj(app.HistPlot,'tag','threshold_line'));
            hold(app.HistPlot,"on")
            plot(app.HistPlot,[threshold; threshold],Y','-r','linewidth',2,'tag','threshold_line');
            hold(app.HistPlot,"off")

            X = app.PercentileAxes.XLim;
            delete(findobj(app.PercentileAxes,'tag','threshold_line'));
            hold(app.PercentileAxes,"on")
            plot(app.PercentileAxes,X',[threshold; threshold],'-r','linewidth',2,'tag','threshold_line');
            hold(app.PercentileAxes,"off")

        end

        % Value changed function: ZEditField
        function ZVal_change(app, event)
            app.ZSlider.Value = app.ZEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.imgAX = uiaxes(app.UIFigure);
            app.imgAX.XTick = [];
            app.imgAX.YTick = [];
            app.HistPlot = uiaxes(app.UIFigure);
            title(app.HistPlot, 'Histogram')
            xlabel(app.HistPlot, 'Intensity')
            ylabel(app.HistPlot, 'Counts')
            zlabel(app.HistPlot, 'Z')
            app.PercentileAxes = uiaxes(app.UIFigure);
            title(app.PercentileAxes, 'Percentile')
            xlabel(app.PercentileAxes, 'X')
            ylabel(app.PercentileAxes, 'Y')
            zlabel(app.PercentileAxes, 'Z')
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z =';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ThresholdCheckBox = uicheckbox(app.UIFigure);
            app.ThresholdCheckBox.Text = '';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @threshold_value_change, true);
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZVal_change, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end